classdef APFDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SCDropDown            matlab.ui.control.DropDown
        SC_ENCheckBox         matlab.ui.control.CheckBox
        SRDropDown            matlab.ui.control.DropDown
        SR_ENCheckBox         matlab.ui.control.CheckBox
        HzLabel3              matlab.ui.control.Label
        RLEditField           matlab.ui.control.EditField
        RLEditFieldLabel      matlab.ui.control.Label
        DownButton            matlab.ui.control.Button
        UpButton              matlab.ui.control.Button
        FixValueButtonGroup   matlab.ui.container.ButtonGroup
        OhmLabel              matlab.ui.control.Label
        FLabel                matlab.ui.control.Label
        REditField            matlab.ui.control.EditField
        CEditField            matlab.ui.control.EditField
        RButton               matlab.ui.control.RadioButton
        CButton               matlab.ui.control.RadioButton
        HzLabel2              matlab.ui.control.Label
        fstopEditField        matlab.ui.control.EditField
        fstopEditFieldLabel   matlab.ui.control.Label
        HzLabel               matlab.ui.control.Label
        fstartEditField       matlab.ui.control.EditField
        fstartEditFieldLabel  matlab.ui.control.Label
        OrderEditField        matlab.ui.control.NumericEditField
        OrderEditFieldLabel   matlab.ui.control.Label
        TypeDropDown          matlab.ui.control.DropDown
        TypeDropDownLabel     matlab.ui.control.Label
        SimButton             matlab.ui.control.Button
        SynthesisButton       matlab.ui.control.Button
        UITable_para          matlab.ui.control.Table
        Image_sch             matlab.ui.control.Image
        UIAxes_plot           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = funSynthesis(app)
            results = [];
            Fst = Suffix2Data(app.fstartEditField.Value);
            Fsp = Suffix2Data(app.fstopEditField.Value);
            n   = app.OrderEditField.Value;
            Type = app.TypeDropDown.Value;
            switch Type
                case 'RC'
                    [fx] = funPolyPhaseNetwork(Fst, Fsp, n);
                case 'OP'
                    [fx] = funAllPassFilter(Fst, Fsp, n*2);
            end
            sel = app.FixValueButtonGroup.SelectedObject.Text;
            if sel == 'C'
                C = Suffix2Data(app.CEditField.Value).*ones(1,length(fx));
                R = 1./(2*pi*C.*fx);
            else
                R = Suffix2Data(app.REditField.Value).*ones(1,length(fx));
                C = 1./(2*pi*R.*fx);
            end
            results.R  = R;
            results.C  = C;
            results.fx = fx;
            results.n  = n;
            results.Type = Type;
            results.Fst = Fst;
            results.Fsp = Fsp;
        end
        
        function results = funSynthesisAndUpdateTab(app)
            results = app.funSynthesis();
            R    = results.R;
            C    = results.C;
            fx   = results.fx;
            n    = results.n;
            Type = results.Type;
            t = [];
            switch Type
                case 'RC'
                    for ii=1:n
                        t{ii, 1} = ii;
                        t{ii, 2} = Data2Suffix(R(ii),  '0.3');
                        t{ii, 3} = Data2Suffix(C(ii),  '0.3');
                        t{ii, 4} = Data2Suffix(fx(ii), '0.3');
                        t{ii, 5} = '---';
                        t{ii, 6} = '---';
                        t{ii, 7} = '---';
                    end
                    
                case 'OP'
                    RI = abs(R(fx>0));
                    CI = abs(C(fx>0));
                    RQ = abs(R(fx<0));
                    CQ = abs(C(fx<0));
                    FI = fx(fx>0);
                    FQ = -fx(fx<0);
                    for ii=1:n
                        t{ii, 1} = ii;
                        t{ii, 2} = Data2Suffix(RI(ii),  '0.3');
                        t{ii, 3} = Data2Suffix(CI(ii),  '0.3');
                        t{ii, 4} = Data2Suffix(FI(ii), '0.3');
                        t{ii, 5} = Data2Suffix(RQ(ii),  '0.3');
                        t{ii, 6} = Data2Suffix(CQ(ii),  '0.3');
                        t{ii, 7} = Data2Suffix(FQ(ii), '0.3');
                    end
            end
            results.t = t;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global G_APF;
            G_APF.ScaleY = 1;% +/-1 degree
            app.UIFigure.Icon = './src/MyAPF.png';
            app.UIFigure.Name = 'APF Design App';
            app.Image_sch.ImageSource = './src/apf_sch_nth.png';
            app.TypeDropDown.Value = 'RC';
            app.SRDropDown.Enable = 0;
            app.SCDropDown.Enable = 0;
            app.RButton.Value = 1;
            app.SynthesisButtonPushed();
            app.SimButtonPushed();
            G_APF.DisplayData = app.UITable_para.Data;
        end

        % Button pushed function: SynthesisButton
        function SynthesisButtonPushed(app, event)
            global G_APF;
            results = app.funSynthesisAndUpdateTab();
            t    = results.t;
            % actual value en?
            % C first
            if app.SC_ENCheckBox.Value
                SC_En = app.SCDropDown.Value;
                [nC, EnC] = funGetRLCEn(SC_En);
                n = results.n;
                for ii=1:n
                    if ~strcmp(t{ii, 3}, '---')
                        mt  = Suffix2Data(t{ii, 3});
                        mt2 = funIdeal2ActualValue(mt, nC, EnC);
                        t{ii, 3} = Data2Suffix(mt2, '0.3');
                        % calcu R
                        freq0 = Suffix2Data(t{ii, 4});
                        mt3 = 1/(2*pi*mt2*freq0);
                        t{ii, 2} = Data2Suffix(mt3, '0.3');
                    end
                    if ~strcmp(t{ii, 6}, '---')
                        mt  = Suffix2Data(t{ii, 6});
                        mt2 = funIdeal2ActualValue(mt, nC, EnC);
                        t{ii, 6} = Data2Suffix(mt2, '0.3');
                        % calcu R
                        freq0 = Suffix2Data(t{ii, 7});
                        mt3 = 1/(2*pi*mt2*freq0);
                        t{ii, 5} = Data2Suffix(mt3, '0.3');
                    end
                end
            end
            % then R Value
            if app.SR_ENCheckBox.Value
                SR_En = app.SRDropDown.Value;
                [nR, EnR] = funGetRLCEn(SR_En);
                n = results.n;
                for ii=1:n
                    if ~strcmp(t{ii, 2}, '---')
                        mt  = Suffix2Data(t{ii, 2});
                        mt2 = funIdeal2ActualValue(mt, nR, EnR);
                        t{ii, 2} = Data2Suffix(mt2, '0.3');
                        % calcu Freq
                        C = Suffix2Data(t{ii, 3});
                        mt3 = 1/(2*pi*mt2*C);
                        t{ii, 4} = Data2Suffix(mt3, '0.3');
                    end
                    if ~strcmp(t{ii, 5}, '---')
                        mt  = Suffix2Data(t{ii, 5});
                        mt2 = funIdeal2ActualValue(mt, nR, EnR);
                        t{ii, 5} = Data2Suffix(mt2, '0.3');
                        % calcu Freq
                        C = Suffix2Data(t{ii, 6});
                        mt3 = 1/(2*pi*mt2*C);
                        t{ii, 7} = Data2Suffix(mt3, '0.3');
                    end
                end
            end
            app.UITable_para.Data=t;            
            G_APF.DisplayData = app.UITable_para.Data;
        end

        % Button pushed function: SimButton
        function SimButtonPushed(app, event)
            global G_APF;
            Fst = Suffix2Data(app.fstartEditField.Value);
            Fsp = Suffix2Data(app.fstopEditField.Value);
            t   = app.UITable_para.Data;
            n   = app.OrderEditField.Value;
            f = logspace(floor(log10(Fst)), ceil(log10(Fsp)), 100);
            [a, b] = size(t);
            Type = app.TypeDropDown.Value;
            switch Type
                case 'RC'
                    RL = Suffix2Data(app.RLEditField.Value);
                    R = [];
                    C = [];
                    for ii=1:a
                        R(ii) = Suffix2Data(t{ii, 2});
                        C(ii) = Suffix2Data(t{ii, 3});
                    end
                    [ang, Suppression] = funAnaPN(R, C, RL, f);
                    ang = ang*180/pi;
                case 'OP'
                    RI = [];
                    CI = [];
                    RQ = [];
                    CQ = [];
                    for ii=1:a
                        RI(ii) = Suffix2Data(t{ii, 2});
                        CI(ii) = Suffix2Data(t{ii, 3});
                        RQ(ii) = Suffix2Data(t{ii, 5});
                        CQ(ii) = Suffix2Data(t{ii, 6});
                    end
                    R = [RI, -RQ];
                    C = [CI, CQ];
                    [ang, Suppression] = funAnaOP(R, C, f);
                    ang = ang*180/pi;
            end
            
            semilogx(app.UIAxes_plot, f, ang, '-r', 'Linewidth', 2);
            set(app.UIAxes_plot, 'XLimMode', 'auto');
            set(app.UIAxes_plot, 'XTickMode', 'auto');
            set(app.UIAxes_plot, 'XTickLabelMode', 'auto');
            grid(app.UIAxes_plot, 'on');
            ScaleY = G_APF.ScaleY;
            ylim(app.UIAxes_plot,[90-ScaleY,90+ScaleY]);
            xlim(app.UIAxes_plot,[min(f), max(f)]);
            xlabel(app.UIAxes_plot, 'Freq/Hz');
            ylabel(app.UIAxes_plot, 'Phase/degree');
            title(app.UIAxes_plot, sprintf('APF, Order:%d', n));
            XTik = get(app.UIAxes_plot, 'XTick');
            for ii=1:length(XTik)
                [cellDataOut{ii}]=Data2Suffix(XTik(ii));
            end
            set(app.UIAxes_plot, 'XTickLabel', cellDataOut);
        end

        % Display data changed function: UITable_para
        function UITable_paraDisplayDataChanged(app, event)
            global G_APF;
            newDisplayData = app.UITable_para.DisplayData;
            orgDisplayData = G_APF.DisplayData;
            sel = app.FixValueButtonGroup.SelectedObject.Text;
            [a, b]=size(orgDisplayData);
            for ii=1:a
                for jj=1:b
                    TabEq(ii, jj) = ~isequal(orgDisplayData(ii,jj), newDisplayData(ii,jj));
                end
            end
            [a1, b1] = find(TabEq==1);
            if b1==2 || b1==5 % R
                R    = Suffix2Data(newDisplayData{a1, b1});
                if sel == 'C' % fix C
                    C    = Suffix2Data(newDisplayData{a1, b1+1});
                    Freq = 1/(2*pi*C*R);
                    newDisplayData{a1, b1+2} = Data2Suffix(Freq,'0.3');
                else % fix R
                    Freq = Suffix2Data(newDisplayData{a1, b1+2});
                    C = 1/(2*pi*Freq*R);
                    newDisplayData{a1, b1+1} = Data2Suffix(C,'0.3');
                end
            elseif b1==3 || b1==6 % C
                C    = Suffix2Data(newDisplayData{a1, b1});
                if sel == 'C' % fix C
                    Freq = Suffix2Data(newDisplayData{a1, b1+1});
                    R = 1/(2*pi*Freq*C);
                    newDisplayData{a1, b1-1} = Data2Suffix(R,'0.3');
                else % fix R
                    R = Suffix2Data(newDisplayData{a1, b1-1});
                    Freq = 1/(2*pi*C*R);
                    newDisplayData{a1, b1+1} = Data2Suffix(Freq,'0.3');
                end
            elseif b1==4 || b1==7 % Freq
                Freq = Suffix2Data(newDisplayData{a1, b1});
                if sel == 'C'
                    C    = Suffix2Data(newDisplayData{a1, b1-1});
                    R    = 1/(2*pi*Freq*C);
                    newDisplayData{a1, b1-2} = Data2Suffix(R,'0.3');
                else
                    R    = Suffix2Data(newDisplayData{a1, b1-2});
                    C    = 1/(2*pi*Freq*R);
                    newDisplayData{a1, b1-1} = Data2Suffix(C,'0.3');
                end
            else
                warning('Can not modified this value\n');
                 newDisplayData = G_APF.DisplayData;
            end
            app.UITable_para.Data = newDisplayData;
            G_APF.DisplayData = newDisplayData;
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            global G_APF;
            value = app.TypeDropDown.Value;
            switch value
                case 'OP'
                    app.Image_sch.ImageSource = './src/pn_sch_nth.png';
                    app.RLEditField.Enable = 0;
                case 'RC'
                    app.Image_sch.ImageSource = './src/apf_sch_nth.png';
                    app.RLEditField.Enable = 1;
                otherwise
            end
            app.SynthesisButtonPushed();
            app.SimButtonPushed();
            G_APF.DisplayData = app.UITable_para.Data;
        end

        % Value changed function: OrderEditField
        function OrderEditFieldValueChanged(app, event)
            global G_APF;
            value = app.OrderEditField.Value;
            value = round(value);
            app.OrderEditField.Value = value;
            app.SynthesisButtonPushed();
            app.SimButtonPushed();
            G_APF.DisplayData = app.UITable_para.Data;
        end

        % Selection changed function: FixValueButtonGroup
        function FixValueButtonGroupSelectionChanged(app, event)
%             selectedButton = app.FixValueButtonGroup.SelectedObject;
%             global G_APF;
%             app.SynthesisButtonPushed();
%             app.SimButtonPushed();
%             G_APF.DisplayData = app.UITable_para.Data;
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            global G_APF;
            ScaleY = G_APF.ScaleY;
            Scale2 = 10^floor(log10(ScaleY));
            Scale1 = fix(ScaleY/Scale2);
            switch Scale1
                case 1
                    Scale1 = 2;
                case 2
                    Scale1 = 5;
                case 5
                    Scale1 = 1;
                    Scale2 = Scale2*10;
                otherwise
                    Scale1 = 1;
            end
            ScaleY = Scale1*Scale2;
            if ScaleY>10
                ScaleY = 10;
            end
            G_APF.ScaleY = ScaleY;
            ylim(app.UIAxes_plot,[90-ScaleY,90+ScaleY]);
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            global G_APF;
            ScaleY = G_APF.ScaleY;
            Scale2 = 10^floor(log10(ScaleY));
            Scale1 = fix(ScaleY/Scale2);
            switch Scale1
                case 1
                    Scale1 = 5;
                    Scale2 = Scale2/10;
                case 2
                    Scale1 = 1;
                case 5
                    Scale1 = 2;
                otherwise
                    Scale1 = 1;
            end
            ScaleY = Scale1*Scale2;
            if ScaleY<0.1
                ScaleY = 0.1;
            end
            G_APF.ScaleY = ScaleY;
            ylim(app.UIAxes_plot,[90-ScaleY,90+ScaleY]);
        end

        % Value changed function: RLEditField
        function RLEditFieldValueChanged(app, event)
            global G_APF;
            value = app.RLEditField.Value;
            RL = Suffix2Data(value);
            if isempty(RL)
                RL = 1e6;
                app.RLEditField.Value = Data2Suffix(RL, '0.1');
            end
            app.SimButtonPushed();
            G_APF.DisplayData = app.UITable_para.Data;
        end

        % Value changed function: SR_ENCheckBox
        function SR_ENCheckBoxValueChanged(app, event)
            value = app.SR_ENCheckBox.Value;
            app.SRDropDown.Enable = value;
        end

        % Value changed function: SC_ENCheckBox
        function SC_ENCheckBoxValueChanged(app, event)
            value = app.SC_ENCheckBox.Value;
            app.SCDropDown.Enable = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_plot = uiaxes(app.UIFigure);
            title(app.UIAxes_plot, 'Title')
            xlabel(app.UIAxes_plot, 'X')
            ylabel(app.UIAxes_plot, 'Y')
            zlabel(app.UIAxes_plot, 'Z')
            app.Image_sch = uiimage(app.UIFigure);
            app.UITable_para = uitable(app.UIFigure);
            app.UITable_para.ColumnName = {'k'; 'R_I/Ohm'; 'C_I/F'; 'F_I/Hz'; 'R_Q/Ohm'; 'C_Q/F'; 'F_Q/Hz'};
            app.UITable_para.RowName = {};
            app.UITable_para.ColumnEditable = true;
            app.UITable_para.DisplayDataChangedFcn = createCallbackFcn(app, @UITable_paraDisplayDataChanged, true);
            app.SynthesisButton = uibutton(app.UIFigure, 'push');
            app.SynthesisButton.ButtonPushedFcn = createCallbackFcn(app, @SynthesisButtonPushed, true);
            app.SynthesisButton.Text = 'Synthesis';
            app.SimButton = uibutton(app.UIFigure, 'push');
            app.SimButton.ButtonPushedFcn = createCallbackFcn(app, @SimButtonPushed, true);
            app.SimButton.Text = 'Sim';
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'OP', 'RC'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'OP';
            app.OrderEditFieldLabel = uilabel(app.UIFigure);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrderEditField.Limits = [1 20];
            app.OrderEditField.ValueChangedFcn = createCallbackFcn(app, @OrderEditFieldValueChanged, true);
            app.OrderEditField.Value = 6;
            app.fstartEditFieldLabel = uilabel(app.UIFigure);
            app.fstartEditFieldLabel.Text = 'fstart';
            app.fstartEditField = uieditfield(app.UIFigure, 'text');
            app.fstartEditField.Value = '270';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.fstopEditFieldLabel = uilabel(app.UIFigure);
            app.fstopEditFieldLabel.Text = 'fstop';
            app.fstopEditField = uieditfield(app.UIFigure, 'text');
            app.fstopEditField.Value = '3600';
            app.HzLabel2 = uilabel(app.UIFigure);
            app.HzLabel2.Text = 'Hz';
            app.FixValueButtonGroup = uibuttongroup(app.UIFigure);
            app.FixValueButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FixValueButtonGroupSelectionChanged, true);
            app.FixValueButtonGroup.Title = 'FixValue';
            app.CButton = uiradiobutton(app.FixValueButtonGroup);
            app.CButton.Text = 'C';
            app.CButton.Value = true;
            app.RButton = uiradiobutton(app.FixValueButtonGroup);
            app.RButton.Text = 'R';
            app.CEditField = uieditfield(app.FixValueButtonGroup, 'text');
            app.CEditField.Value = '10n';
            app.REditField = uieditfield(app.FixValueButtonGroup, 'text');
            app.REditField.Value = '12k';
            app.FLabel = uilabel(app.FixValueButtonGroup);
            app.FLabel.Text = 'F';
            app.OhmLabel = uilabel(app.FixValueButtonGroup);
            app.OhmLabel.Text = 'Ohm';
            app.UpButton = uibutton(app.UIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = '^';
            app.DownButton = uibutton(app.UIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'v';
            app.RLEditFieldLabel = uilabel(app.UIFigure);
            app.RLEditFieldLabel.Text = 'RL';
            app.RLEditField = uieditfield(app.UIFigure, 'text');
            app.RLEditField.ValueChangedFcn = createCallbackFcn(app, @RLEditFieldValueChanged, true);
            app.RLEditField.Value = '1M';
            app.HzLabel3 = uilabel(app.UIFigure);
            app.HzLabel3.Text = 'Ohm';
            app.SR_ENCheckBox = uicheckbox(app.UIFigure);
            app.SR_ENCheckBox.ValueChangedFcn = createCallbackFcn(app, @SR_ENCheckBoxValueChanged, true);
            app.SR_ENCheckBox.Text = 'SR_EN';
            app.SRDropDown = uidropdown(app.UIFigure);
            app.SRDropDown.Items = {'E6', 'E12', 'E24', 'E48', 'E96', 'E192'};
            app.SRDropDown.Value = 'E96';
            app.SC_ENCheckBox = uicheckbox(app.UIFigure);
            app.SC_ENCheckBox.ValueChangedFcn = createCallbackFcn(app, @SC_ENCheckBoxValueChanged, true);
            app.SC_ENCheckBox.Text = 'SC_EN';
            app.SCDropDown = uidropdown(app.UIFigure);
            app.SCDropDown.Items = {'E6', 'E12', 'E24', 'E48', 'E96', 'E192'};
            app.SCDropDown.Value = 'E24';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APFDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end